classdef game_screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        background_game   matlab.ui.control.Image
        a_button          matlab.ui.control.Button
        b_button          matlab.ui.control.Button
        c_button          matlab.ui.control.Button
        d_button          matlab.ui.control.Button
        question_box      matlab.ui.control.Label
        ListMoney         matlab.ui.control.ListBox
        walkout_icon      matlab.ui.control.Image
        fiftyfifty_icon   matlab.ui.control.Image
        phonefriend_icon  matlab.ui.control.Image
        x1_icon           matlab.ui.control.Image
        x2_icon           matlab.ui.control.Image
        x3_icon           matlab.ui.control.Image
        askaudience_icon  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function results = game_playmusic(app) % Creates function to play background music from mp3 file
            [x, z] = audioread('game_music.mp3');
            sound(x, z);
        end
        
        function results = q2_func(app) % Creates function that runs the second round of the game and asks the second question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==2 % Runs code only if it is round two using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value= '$ 200'
                money_earned='$ 100' 
                money_secured = '$ 0'
                question_2 = {'Which legal document states the disposal of property after death?','Which of these is not a synonym of happy?','Which of these items is most likely to be found in the kitchen?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_2(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Would'
                    app.b_button.Text='Shall'
                    app.c_button.Text='Will'
                    app.d_button.Text='Should'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Content'
                    app.b_button.Text='Apprehensive'
                    app.c_button.Text='Joyful'
                    app.d_button.Text='Jovial'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Toolbox'
                    app.b_button.Text='Toilet'
                    app.c_button.Text='Nightstand'
                    app.d_button.Text='Range'
                end
            end
        end
        
        function results = q3_func(app) % Creates function that runs the third round of the game and asks the third question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==3 % Runs code only if it is round three using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 300'
                money_earned='$ 200'
                money_secured = '$ 0'
                question_3 = {'Which of the following is the title of a James Bond film?', 'Which of these animals is not a quadruped?','What is the square root of 4?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_3(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Dr. Oz'
                    app.b_button.Text='Lightning'
                    app.c_button.Text='Casino Royale'
                    app.d_button.Text='Chamber of Secrets'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Dog'
                    app.b_button.Text='Crane'
                    app.c_button.Text='Chameleon'
                    app.d_button.Text='Crocodile'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='4'
                    app.b_button.Text='1'
                    app.c_button.Text='16'
                    app.d_button.Text='2'
                end
            end
        end
        
        function results = q4_func(app) % Creates function that runs the fourth round of the game and asks the fourth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==4 % Runs code only if it is round four using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 500'
                money_earned='$ 300'
                money_secured = '$ 0'
                question_4 = {'What is the official language of Burkina Faso?','Which of these is not a social media platform?','Which Office program lets you create spreadsheets and formulas?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_4(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='English'
                    app.b_button.Text='Cantonese'
                    app.c_button.Text='French'
                    app.d_button.Text='Swahili'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Snapchat'
                    app.b_button.Text='Netflix'
                    app.c_button.Text='Twitter'
                    app.d_button.Text='WhatsApp'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Word'
                    app.b_button.Text='PowerPoint'
                    app.c_button.Text='Outlook'
                    app.d_button.Text='Excel'
            end
        end
    end
        
        function results = q5_func(app) % Creates function that runs the fifth round of the game and asks the fifth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==5 % Runs code only if it is round five using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 1,000'
                money_earned='$ 500'
                money_secured = '$ 0'
                question_5 = {'In which sport do two teams pull at opposite ends of a rope?','Which is not a human body part?','Which of these is not a classical music artist?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_5(q_number);
                drawnow;    
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Boxing'
                    app.b_button.Text='Tennis'
                    app.c_button.Text='Tug of War'
                    app.d_button.Text='Squash'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Spleen'
                    app.b_button.Text='Chloroplast'
                    app.c_button.Text='Aorta'
                    app.d_button.Text='Tonsils'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Claude Debussy'
                    app.b_button.Text='Glenn Gould'
                    app.c_button.Text='Sebastian Bach'
                    app.d_button.Text='Tycho Brahe'
            end
        end
    end
        
        function results = q6_func(app) % Creates function that runs the sixth round of the game and asks the sixth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==6 % Runs code only if it is round six using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 2,000'
                money_earned='$ 1,000'
                money_secured = '$ 1,000'
                question_6 = {'In Greek mythology, who is the god of war?','Which of the following female tennis players was born in the US?','Which of the following is a Four-Corner state?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_6(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Apollo'
                    app.b_button.Text='Athena'
                    app.c_button.Text='Ares'
                    app.d_button.Text='Artemis'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Maria Sharapova'
                    app.b_button.Text='Venus Williams'
                    app.c_button.Text='Garbiñe Muguruza'
                    app.d_button.Text='Steffi Graf'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Oregon'
                    app.b_button.Text='North Dakota'
                    app.c_button.Text='Florida'
                    app.d_button.Text='Utah'
            end
        end
    end
        
        function results = q7_func(app) % Creates function that runs the seventh round of the game and asks the seventh question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==7 % Runs code only if it is round seven using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 4,000'
                money_earned='$ 2,000'
                money_secured = '$ 1,000'
                question_7 = {'How many US state names start with the letter A?','What is the name of the second tallest mountain peak on Earth?','Which word describes a piece of knitting with a series of raised rows?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_7(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='2'
                    app.b_button.Text='3'
                    app.c_button.Text='4'
                    app.d_button.Text='5'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Mount Everest'
                    app.b_button.Text='K2'
                    app.c_button.Text='Olympus Mons'
                    app.d_button.Text='Aconcagua'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Hipped'
                    app.b_button.Text='Skulled'
                    app.c_button.Text='Spined'
                    app.d_button.Text='Ribbed'
            end
        end
    end
        
        function results = q8_func(app) % Creates function that runs the eighth round of the game and asks the eighth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==8 % Runs code only if it is round eight using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 8,000'
                money_earned='$ 4,000'
                money_secured = '$ 1,000'
                question_8 = {'Which is the smallest country in South America by land area?','Which university in the world has produced the most Nobel laureates?','Which city is the capital of Honduras?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_8(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Uruguay'
                    app.b_button.Text='Ecuador'
                    app.c_button.Text='Suriname'
                    app.d_button.Text='Guyana'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Stanford'
                    app.b_button.Text='Harvard'
                    app.c_button.Text='Oxford'
                    app.d_button.Text='Cambridge'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='San Jose';
                    app.b_button.Text='San Pedro Sula'
                    app.c_button.Text='San Salvador'
                    app.d_button.Text='Tegucigalpa'
            end
        end
    end
        
        function results = q9_func(app) % Creates function that runs the ninth round of the game and asks the ninth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==9 % Runs code only if it is round nine using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 16,000'
                money_earned='$ 8,000'
                money_secured = '$ 1,000'
                question_9 = {'Which famous Romanian gymnast participated in the Montreal 1976 Olympics?','What was the name of the final imperial dynasty of China?','Which is the most-viewed Youtube music video as of 2019?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_9(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Laura Cutina'
                    app.b_button.Text='Oana Ban'
                    app.c_button.Text='Nadia Comaneci'
                    app.d_button.Text='Olga Korbut'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Zhou'
                    app.b_button.Text='Qing'
                    app.c_button.Text='Han'
                    app.d_button.Text='Jin'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Bad Guy'
                    app.b_button.Text='Baby'
                    app.c_button.Text='Shape of You'
                    app.d_button.Text='Despacito'
            end
        end
        end
        
        function results = q10_func(app) % Creates function that runs the tenth round of the game and asks the tenth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==10 % Runs code only if it is round ten using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 32,000'
                money_earned='$ 16,000'
                money_secured = '$ 1,000'
                question_10 = {'What does D in D-Day stand for?','Who invented the telescope?','Which of the following is not a moon of Jupiter?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_10(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Doom'
                    app.b_button.Text='Deliverance'
                    app.c_button.Text='No meaning'
                    app.d_button.Text='Dead'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Galileo Galilei'
                    app.b_button.Text='Hans Lippershey'
                    app.c_button.Text='Johannes Kepler'
                    app.d_button.Text='Tycho Brahe'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Callisto'
                    app.b_button.Text='Io'
                    app.c_button.Text='Amalthea'
                    app.d_button.Text='Proteus'
            end
        end
    end
        
        function results = q11_func(app) % Creates function that runs the eleventh round of the game and asks the eleventh question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==11 % Runs code only if it is round eleven using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 64,000'
                money_earned='$ 32,000'
                money_secured = '$ 32,000'
                question_11 = {'What was the name of the first dog in space?','In what year was the Berlin wall built?','Which fruit is the principal ingredient of a traditional tarte tatin?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_11(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Atlantis'
                    app.b_button.Text='Sputnik'
                    app.c_button.Text='Laika'
                    app.d_button.Text='Discovery'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='1959'
                    app.b_button.Text='1961'
                    app.c_button.Text='1963'
                    app.d_button.Text='1991'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Peach'
                    app.b_button.Text='Mango'
                    app.c_button.Text='Pineapple'
                    app.d_button.Text='Apple'
                end
            end
        end
        
        function results = q12_func(app) % Creates function that runs the twelfth round of the game and asks the twelfth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==12 % Runs code only if it is round twelve using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 125,000'
                money_earned='$ 64,000'
                money_secured = '$ 32,000'
                question_12 = {'Who played Long John Silver in the film Muppet Treasure Island?','The young of which creature is known as a squab?','Who is the patron saint of Spain?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_12(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Tim Rice'
                    app.b_button.Text='Tim Brooke-Taylor'
                    app.c_button.Text='Tim Curry'
                    app.d_button.Text='Tim McInnerny'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Horse'
                    app.b_button.Text='Pigeon'
                    app.c_button.Text='Octopus'
                    app.d_button.Text='Salmon'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Sain John'
                    app.b_button.Text='Saint Paul'
                    app.c_button.Text='Saint Benedict'
                    app.d_button.Text='Saint James'
                end
        end
        end
        
        function results = q13_func(app) % Creates function that runs the thirteenth round of the game and asks the thirteenth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==13 % Runs code only if it is round thirteen using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 250,000'
                money_earned='$ 125,000'
                money_secured = '$ 32,000'
                question_13 = {'What was the occupation of the American president Thomas Jefferson?','Which male tennis player has won the most Austrlian Open titles?','Which actress portrayed the character Amy Dunne in the thriller movie Gone Girl?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_13(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Dentist'
                    app.b_button.Text='Librarian'
                    app.c_button.Text='Lawyer'
                    app.d_button.Text='Carpenter'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Roger Federer'
                    app.b_button.Text='Novak Djokovic'
                    app.c_button.Text='Pete Sampras'
                    app.d_button.Text='Rafael Nadal'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Felicity Jones'
                    app.b_button.Text='Octavia Spencer'
                    app.c_button.Text='Angelina Jolie'
                    app.d_button.Text='Rosamund Pike'
                end    
        end
    end
        
        function results = q14_func(app) % Creates function that runs the fourteenth round of the game and asks the fourteenth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==14 % Runs code only if it is round fourteen using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 500,000'
                money_earned='$ 250,000'
                money_secured = '$ 32,000'
                question_14 = {'Which pharaoh launched the construction of the first Giza pyramid?','Which war did nurse Florence Nightingale serve in?','Who painted the 1669 painting The Return of the Prodigal Son?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_14(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Cleopatra'
                    app.b_button.Text='Pharaoh Khafre'
                    app.c_button.Text='Pharaoh Khufu'
                    app.d_button.Text='Pharaoh Menkaure'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Civil War'
                    app.b_button.Text='Crimean War'
                    app.c_button.Text='Third Seminole War'
                    app.d_button.Text='Seventh Xhosa War'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Johannes Vermeer'
                    app.b_button.Text='El Greco'
                    app.c_button.Text='Jan Steen'
                    app.d_button.Text='Rembrandt'
               end
            end
        end 
        
        function results = q15_func(app) % Creates function that runs the fifteenth round of the game and asks the fifteenth question
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            
            if round_number==15 % Runs code only if it is round fifteen using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value = '$ 1 MILLION'
                money_earned='$ 500,000'
                money_secured = '$ 32,000'
                question_15 = {'What king was married to Eleanor of Aquitaine?','Which of these is not one of the American Triple Crown horse races?','The American novel There Are No Children Here takes place in which city?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_15(q_number);
                drawnow;
                
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Henry I'
                    app.b_button.Text='Richard I'
                    app.c_button.Text='Henry II'
                    app.d_button.Text='Henry V'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Belmont Stakes'
                    app.b_button.Text='Arlington Million'
                    app.c_button.Text='Kentucky Derby'
                    app.d_button.Text='Preakness Stakes'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Seattle'
                    app.b_button.Text='Philadelphia'
                    app.c_button.Text='New York City'
                    app.d_button.Text='Chicago'
            end
        end
    end
        
        
        function results = ovation_func(app) % Creates helper function ovation_func that reads the mp3 file for an audio of the audience clapping
            [y, Fs] = audioread('ovation.mp3');
            sound(y, Fs);
        end
        
        function results = win_game(app) % Helper function that will be run whenever there is a million-dollar winner
            global round_number
            if round_number==16 % Runs only if the the user responded to the last question correctly
                clear sound % Turns off the suspenseful background music
                app.question_box.Text='You are a Millionaire!' % Congratulates the user and then closes the app, for the game finishes
                pause(4)
                app.question_box.Text='Thank you for playing!'
                pause(4)
                delete(app)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global q_number % Variable for dictating the type of question it is based off its correct answer choice 
            global round_number % Variable that stores the round the user is playing
            global money_earned % Variable that stores the amount of money the user could potentially win after each correct answer
            global money_secured % Variable that stores the amount of money the user wins if the question is answered incorrectly
            round_number=1; % When the app starts, this variable is set to 1 to indicate that the first round is first to come
            game_playmusic(app) % Calls in helper function to play background music

            if round_number==1 % Runs code only if it is round one using if statement and the value of the round_number variable as conditional
                app.ListMoney.Value= '$ 100'
                money_earned = '$ 0' % Sets both variables to zero because the user has not won any money yet
                money_secured = '$ 0'
                question_1 = {'Which of these is not a mathematical operator?','Which of these means to dislike intensely?','Which of these is not a day of the week?'}; % Creates a matrix of three possible questions, where one will be randomly selected for the user to answer
                q_number=randi([1 3],1); % Randomly chooses one of the three possible questions for the round and updated it on the label
                app.question_box.Text=question_1(q_number);
                drawnow;
            
                if q_number==1 % Sets the different answer choices if the first question is randomly selected
                    app.a_button.Text='Division'
                    app.b_button.Text='Addition'
                    app.c_button.Text='Substituent'
                    app.d_button.Text='Multiplication'
                elseif q_number==2 % Sets the different answer choices if the second question is randomly selected
                    app.a_button.Text='Devise'
                    app.b_button.Text='Detest'
                    app.c_button.Text='Deform'
                    app.d_button.Text='Demonetize'
                else % If the third possible question is selected (q_number is 3)
                    app.a_button.Text='Monday'
                    app.b_button.Text='Wednesday'
                    app.c_button.Text='Friday'
                    app.d_button.Text='Thruday'
                end
            end
        end

        % Button pushed function: a_button
        function a_buttonButtonPushed(app, event)
            global money_secured
            
            % If button a is pressed, the user loses because there is no
            % question in the game with choice A is its correct answer
            % choice
            clear sound % Turns off background music
            app.question_box.Text='Incorrect Answer!' % Changes the text of the label component to alert the user that the game is over
            pause(3)
            earn_msg='Total earnings: '
            lose_string=[earn_msg,money_secured];
            final_losemsg=join(lose_string);
            app.question_box.Text = final_losemsg % Tells the user the prize money earned after risking and losing the game
            pause(3.5)
            app.question_box.Text='Game Over!'
            pause(4)  
            delete(app) % Closes the game
        end

        % Button pushed function: b_button
        function b_buttonButtonPushed(app, event)
            global q_number
            global round_number
            global money_secured
            clear sound % Turns off music after the button is clicked
            
            if q_number==2 % Runs if the correct answer choice is B
                app.question_box.Text='Correct Answer!' % Displays the message on the label component
                ovation_func(app) % Runs helper function to play clapping sounds
                round_number=round_number+1; % Adds a value of one to the variable round_number so that a new helper function with a new question starts running
                pause(4)
                q2_func(app) % Calls the helper functions to proceed to the next question according to the new value of variable round_number
                q3_func(app)
                q4_func(app)
                q5_func(app)
                q6_func(app)
                q7_func(app)
                q8_func(app)
                q9_func(app)
                q10_func(app)
                q11_func(app)
                q12_func(app)
                q13_func(app)
                q14_func(app)
                q15_func(app)
                game_playmusic(app)
                win_game(app)
            else
                app.question_box.Text='Incorrect Answer!' % Changes the text of the label component to alert the user that the game is over
                pause(3)
                earn_msg='Total earnings: '
                lose_string=[earn_msg,money_secured];
                final_losemsg=join(lose_string);
                app.question_box.Text = final_losemsg % Tells the user the prize money earned after risking and losing the game
                pause(3.5)
                app.question_box.Text='Game Over!'
                pause(4)  
                delete(app) % Closes the game
            end
        end

        % Button pushed function: c_button
        function c_buttonButtonPushed(app, event)
            global q_number
            global round_number
            global money_secured
            clear sound % Turns off music after the button is clicked
            
            if q_number==1 % Runs if the correct answer choice is C
                app.question_box.Text='Correct Answer!' % Displays the message on the label component
                ovation_func(app) % Runs helper function to play clapping sounds
                round_number=round_number+1; % Adds a value of one to the variable round_number so that a new helper function with a new question starts running
                pause(4)
                q2_func(app) % Calls the helper functions to proceed to the next question according to the new value of variable round_number
                q3_func(app)
                q4_func(app)
                q5_func(app)
                q6_func(app)
                q7_func(app)
                q8_func(app)
                q9_func(app)
                q10_func(app)
                q11_func(app)
                q12_func(app)
                q13_func(app)
                q14_func(app)
                q15_func(app)
                game_playmusic(app)
                win_game(app)
            else
                app.question_box.Text='Incorrect Answer!' % Changes the text of the label component to alert the user that the game is over
                pause(3)
                earn_msg='Total earnings: '
                lose_string=[earn_msg,money_secured];
                final_losemsg=join(lose_string);
                app.question_box.Text = final_losemsg % Tells the user the prize money earned after risking and losing the game
                pause(3.5)
                app.question_box.Text='Game Over!'
                pause(4)  
                delete(app) % Closes the game
            end
        end

        % Button pushed function: d_button
        function d_buttonButtonPushed(app, event)
            global q_number
            global round_number
            global money_secured
            clear sound % Turns off music after the button is clicked
            
            if q_number==3 % Runs if the correct answer choice is D
                app.question_box.Text='Correct Answer!' % Displays the message on the label component
                ovation_func(app) % Runs helper function to play clapping sounds
                round_number=round_number+1; % Adds a value of one to the variable round_number so that a new helper function with a new question starts running
                pause(4)
                q2_func(app) % Calls the helper functions to proceed to the next question according to the new value of variable round_number
                q3_func(app)
                q4_func(app)
                q5_func(app)
                q6_func(app)
                q7_func(app)
                q8_func(app)
                q9_func(app)
                q10_func(app)
                q11_func(app)
                q12_func(app)
                q13_func(app)
                q14_func(app)
                q15_func(app)
                game_playmusic(app)
                win_game(app)
            else
                app.question_box.Text='Incorrect Answer!' % Changes the text of the label component to alert the user that the game is over
                pause(3)
                earn_msg='Total earnings: '
                lose_string=[earn_msg,money_secured];
                final_losemsg=join(lose_string);
                app.question_box.Text = final_losemsg % Tells the user the prize money earned after risking and losing the game
                pause(3.5)
                app.question_box.Text='Game Over!'
                pause(4)  
                delete(app) % Closes the game
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app) % The app is closed and the background music is turned off when the app window closing button is clicked
            clear sound
        end

        % Image clicked function: walkout_icon
        function walkout_iconClicked(app, event)
            global money_earned
            
            walkout=questdlg('Are you sure you want to walk out?','Walk Out','Yes','No','No'); % Creates question dialogue pop-up with the option to either walk away or not
            
            switch walkout
                case 'Yes' % If the user clicks on the Yes button
                    clear sound % Turns off the background music and ends the game
                    app.question_box.Text='Game Over!'
                    pause(4)
                    earn_msg='You won '
                    lose_string=[earn_msg,money_earned];
                    final_losemsg=join(lose_string);
                    app.question_box.Text = final_losemsg % Updates label text and declares the prize money earned by the user taking into consideration that the user walked away with the money and did not lose any for answering incorrectly
                    pause(3.5)
                    delete(app)
                case 'No' % Nothing happens if the user clicks on the No button, and the game resumes     
            end
            
        end

        % Image clicked function: fiftyfifty_icon
        function fiftyfifty_iconImageClicked(app, event)
            global q_number
            
            if q_number==1 % Erases answer choice B and D if the correct answer is C
                app.b_button.Text=''
                app.d_button.Text=''
            else % Erases answer choices A and C if the correct answer is either B or D
                app.a_button.Text=''
                app.c_button.Text=''
            end
            delete(app.fiftyfifty_icon) % Erases the lifeline and adds an "x" on top to indicate that the lifeline has already been used
            app.x1_icon.Visible='on'
        end

        % Image clicked function: phonefriend_icon
        function phonefriend_iconImageClicked(app, event)
            global q_number
            
            if q_number==1 % If the correct answer choice is C
                random_answer=['A', 'B', 'C', 'C', 'C', 'C', 'C', 'C', 'C', 'C', 'C', 'D']; % Creates a matrix that will offer 75 percent probability of choosing the correct answer choice
                rand_num=randi([1 12],1); % Chooses a random element from the matrix as an answer to try to help the user
                answer_given=random_answer(rand_num)
                message_intro=['I think the answer is ']
                strings_message=[message_intro, answer_given]
                message_pop=join(strings_message) % Creates message that declares the answer chosen by the friend
                msgbox(message_pop,'Phone a Friend','help') % Creates a message box with a message from the friend offering help
            elseif q_number==2 % If the correct answer choice is B
                random_answer={'A', 'B', 'B', 'B', 'B', 'B', 'B', 'B', 'B', 'B', 'C', 'D'}; % Creates a matrix that will offer 75 percent probability of choosing the correct answer choice
                rand_num=randi([1 12],1); % Chooses a random element from the matrix as an answer to try to help the user
                answer_given=random_answer(rand_num);
                message_intro=['I think the answer is ']
                strings_message=[message_intro, answer_given]
                message_pop=join(strings_message) % Creates message that declares the answer chosen by the friend
                msgbox(message_pop,'Phone a Friend','help') % Creates a message box with a message from the friend offering help
            else % If the correct answer choice is neither B nor C
                random_answer={'A', 'B', 'C', 'D', 'D', 'D', 'D', 'D', 'D', 'D', 'D', 'D'}; % Creates a matrix that will offer 75 percent probability of choosing the correct answer choice
                rand_num=randi([1 12],1); % Chooses a random element from the matrix as an answer to try to help the user
                answer_given=random_answer(rand_num);
                message_intro=['I think the answer is ']
                strings_message=[message_intro, answer_given]
                message_pop=join(strings_message) % Creates message that declares the answer chosen by the friend
                msgbox(message_pop,'Phone a Friend','help') % Creates a message box with a message from the friend offering help
            end
            delete(app.phonefriend_icon) % Erases the lifeline and adds an "x" on top to indicate that the lifeline has already been used
            app.x2_icon.Visible='on'
        end

        % Image clicked function: askaudience_icon
        function askaudience_iconImageClicked(app, event)
            global q_number
            
            if q_number==1 % If the correct answer choice is C, then the game will show a jpg file that indicates that C is the most-selected answer by the audience
                f=imread('audience_answerc.jpg');
                imshow(f)
            else % If the correct answer choice is B or D, then the game will show a jpg file that indicates that D is the most-selected answer by the audience
                f=imread('audience_answerd.jpg');
                imshow(f)
            end
            delete(app.askaudience_icon) % Erases the lifeline and adds an "x" on top to indicate that the lifeline has already been used
            app.x3_icon.Visible='on'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.background_game = uiimage(app.UIFigure);
            app.background_game.ImageSource = 'gamescreen.jpg';
            app.a_button = uibutton(app.UIFigure, 'push');
            app.a_button.ButtonPushedFcn = createCallbackFcn(app, @a_buttonButtonPushed, true);
            app.a_button.Text = '';
            app.b_button = uibutton(app.UIFigure, 'push');
            app.b_button.ButtonPushedFcn = createCallbackFcn(app, @b_buttonButtonPushed, true);
            app.b_button.Text = '';
            app.c_button = uibutton(app.UIFigure, 'push');
            app.c_button.ButtonPushedFcn = createCallbackFcn(app, @c_buttonButtonPushed, true);
            app.c_button.Text = '';
            app.d_button = uibutton(app.UIFigure, 'push');
            app.d_button.ButtonPushedFcn = createCallbackFcn(app, @d_buttonButtonPushed, true);
            app.d_button.Text = '';
            app.question_box = uilabel(app.UIFigure);
            app.question_box.Text = '';
            app.ListMoney = uilistbox(app.UIFigure);
            app.ListMoney.Items = {'$ 1 MILLION', '$ 500,000', '$ 250,000', '$ 125,000', '$ 64,000', '$ 32,000', '$ 16,000', '$ 8,000', '$ 4,000', '$ 2,000', '$ 1,000', '$ 500', '$ 300', '$ 200', '$ 100'};
            app.ListMoney.Value = {};
            app.walkout_icon = uiimage(app.UIFigure);
            app.walkout_icon.ImageClickedFcn = createCallbackFcn(app, @walkout_iconClicked, true);
            app.walkout_icon.ImageSource = 'walk_out.png';
            app.fiftyfifty_icon = uiimage(app.UIFigure);
            app.fiftyfifty_icon.ImageClickedFcn = createCallbackFcn(app, @fiftyfifty_iconImageClicked, true);
            app.fiftyfifty_icon.ImageSource = '50_50.jpg';
            app.phonefriend_icon = uiimage(app.UIFigure);
            app.phonefriend_icon.ImageClickedFcn = createCallbackFcn(app, @phonefriend_iconImageClicked, true);
            app.phonefriend_icon.ImageSource = 'phone_friend.jpg';
            app.x1_icon = uiimage(app.UIFigure);
            app.x1_icon.Visible = 'off';
            app.x1_icon.ImageSource = 'cross_out.png';
            app.x2_icon = uiimage(app.UIFigure);
            app.x2_icon.Visible = 'off';
            app.x2_icon.ImageSource = 'cross_out.png';
            app.x3_icon = uiimage(app.UIFigure);
            app.x3_icon.Visible = 'off';
            app.x3_icon.ImageSource = 'cross_out.png';
            app.askaudience_icon = uiimage(app.UIFigure);
            app.askaudience_icon.ImageClickedFcn = createCallbackFcn(app, @askaudience_iconImageClicked, true);
            app.askaudience_icon.ImageSource = 'ask_audience.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_screen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end